classdef Ata_Kizilirmak_EE101_TermProject1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        TextArea_2             matlab.ui.control.TextArea
        TextArea_2Label        matlab.ui.control.Label
        Button_12              matlab.ui.control.Button
        Button_11              matlab.ui.control.Button
        arccotButton           matlab.ui.control.Button
        arctanButton           matlab.ui.control.Button
        arcsinButton           matlab.ui.control.Button
        arccosButton           matlab.ui.control.Button
        xButton_2              matlab.ui.control.Button
        lnButton               matlab.ui.control.Button
        xButton                matlab.ui.control.Button
        Button_10              matlab.ui.control.Button
        logButton              matlab.ui.control.Button
        piButton               matlab.ui.control.Button
        cotButton              matlab.ui.control.Button
        EXPButton              matlab.ui.control.Button
        tanButton              matlab.ui.control.Button
        sinButton              matlab.ui.control.Button
        Button_Multiplication  matlab.ui.control.Button
        Button_Division        matlab.ui.control.Button
        Button_Addition        matlab.ui.control.Button
        Button_Subtraction     matlab.ui.control.Button
        Button_Equal           matlab.ui.control.Button
        Button_Dot             matlab.ui.control.Button
        Button_000             matlab.ui.control.Button
        Button_0               matlab.ui.control.Button
        Button_3               matlab.ui.control.Button
        Button_2               matlab.ui.control.Button
        Button_1               matlab.ui.control.Button
        Button_4               matlab.ui.control.Button
        Button_5               matlab.ui.control.Button
        Button_6               matlab.ui.control.Button
        Button_7               matlab.ui.control.Button
        Button_8               matlab.ui.control.Button
        Button_9               matlab.ui.control.Button
        cosButton              matlab.ui.control.Button
        Button_CE              matlab.ui.control.Button
        TextArea               matlab.ui.control.TextArea
        DeveloperEditField     matlab.ui.control.EditField
        Label_2                matlab.ui.control.Label
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_0, Button_000, Button_1, 
        % Button_10, Button_11, Button_12, Button_2, Button_3, Button_4, 
        % Button_5, Button_6, Button_7, Button_8, Button_9, 
        % Button_Addition, Button_Division, Button_Dot, 
        % Button_Multiplication, Button_Subtraction, piButton
        function Press(app, event)
             app.TextArea.Value = [app.TextArea.Value{1},event.Source.Text];
        end

        % Button pushed function: sinButton
        function PressSin(app, event)
            app.TextArea.Value = num2str(sind(str2double(app.TextArea.Value{1})));
        end

        % Button pushed function: Button_CE
        function PressCE(app, event)
            app.TextArea.Value = '';
        end

        % Button pushed function: cosButton
        function PressCos(app, event)
            app.TextArea.Value = cosd(str2double(app.TextArea.Value{1}));
        end

        % Button pushed function: tanButton
        function PressTan(app, event)
            app.TextArea.Value = num2str(tand(str2double(app.TextArea.Value{1})));
        end

        % Button pushed function: EXPButton
        function PressEXP(app, event)
            app.TextArea.Value = num2str(exp(str2double(app.TextArea.Value{1})));
        end

        % Button pushed function: Button_Equal
        function PressEqual(app, event)
            app.TextArea.Value = num2str(eval(app.TextArea.Value{1}));
        end

        % Button pushed function: cotButton
        function PressCot(app, event)
            app.TextArea.Value = num2str(cotd(str2double(app.TextArea.Value{1})));
        end

        % Button pushed function: logButton
        function PressLog(app, event)
            app.TextArea.Value = num2str(log10(str2double(app.TextArea.Value{1})));
        end

        % Button pushed function: xButton
        function PressFact(app, event)
            app.TextArea.Value = num2str(factorial(str2double(app.TextArea.Value{1})));
        end

        % Button pushed function: lnButton
        function PressLn(app, event)
            app.TextArea.Value = num2str(log(str2double(app.TextArea.Value{1})));
        end

        % Button pushed function: xButton_2
        function PressInv(app, event)
            app.TextArea.Value = num2str(1/(str2double(app.TextArea.Value{1})));
        end

        % Button pushed function: arccosButton
        function PressAcos(app, event)
            app.TextArea.Value = num2str(acosd(str2double(app.TextArea.Value{1})));
        end

        % Button pushed function: arcsinButton
        function PressAsin(app, event)
            app.TextArea.Value = num2str(asind(str2double(app.TextArea.Value{1})));
        end

        % Button pushed function: arctanButton
        function PressAtand(app, event)
            app.TextArea.Value = num2str(atand(str2double(app.TextArea.Value{1})));
        end

        % Button pushed function: arccotButton
        function PressAcotd(app, event)
            app.TextArea.Value = num2str(acotd(str2double(app.TextArea.Value{1})));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = 'Developer:';
            app.DeveloperEditField = uieditfield(app.GridLayout, 'text');
            app.DeveloperEditField.Value = 'Ata Kizilirmak';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Placeholder = '0';
            app.Button_CE = uibutton(app.GridLayout, 'push');
            app.Button_CE.ButtonPushedFcn = createCallbackFcn(app, @PressCE, true);
            app.Button_CE.Text = {'CE'; ''};
            app.cosButton = uibutton(app.GridLayout, 'push');
            app.cosButton.ButtonPushedFcn = createCallbackFcn(app, @PressCos, true);
            app.cosButton.Text = 'cos';
            app.Button_9 = uibutton(app.GridLayout, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Press, true);
            app.Button_9.Text = '9';
            app.Button_8 = uibutton(app.GridLayout, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Press, true);
            app.Button_8.Text = '8';
            app.Button_7 = uibutton(app.GridLayout, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Press, true);
            app.Button_7.Text = '7';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Press, true);
            app.Button_6.Text = '6';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Press, true);
            app.Button_5.Text = '5';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Press, true);
            app.Button_4.Text = '4';
            app.Button_1 = uibutton(app.GridLayout, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Press, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Press, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Press, true);
            app.Button_3.Text = '3';
            app.Button_0 = uibutton(app.GridLayout, 'push');
            app.Button_0.ButtonPushedFcn = createCallbackFcn(app, @Press, true);
            app.Button_0.Text = '0';
            app.Button_000 = uibutton(app.GridLayout, 'push');
            app.Button_000.ButtonPushedFcn = createCallbackFcn(app, @Press, true);
            app.Button_000.Text = '000';
            app.Button_Dot = uibutton(app.GridLayout, 'push');
            app.Button_Dot.ButtonPushedFcn = createCallbackFcn(app, @Press, true);
            app.Button_Dot.Text = '.';
            app.Button_Equal = uibutton(app.GridLayout, 'push');
            app.Button_Equal.ButtonPushedFcn = createCallbackFcn(app, @PressEqual, true);
            app.Button_Equal.Text = '=';
            app.Button_Subtraction = uibutton(app.GridLayout, 'push');
            app.Button_Subtraction.ButtonPushedFcn = createCallbackFcn(app, @Press, true);
            app.Button_Subtraction.Text = '-';
            app.Button_Addition = uibutton(app.GridLayout, 'push');
            app.Button_Addition.ButtonPushedFcn = createCallbackFcn(app, @Press, true);
            app.Button_Addition.Text = '+';
            app.Button_Division = uibutton(app.GridLayout, 'push');
            app.Button_Division.ButtonPushedFcn = createCallbackFcn(app, @Press, true);
            app.Button_Division.Text = '/';
            app.Button_Multiplication = uibutton(app.GridLayout, 'push');
            app.Button_Multiplication.ButtonPushedFcn = createCallbackFcn(app, @Press, true);
            app.Button_Multiplication.Text = '*';
            app.sinButton = uibutton(app.GridLayout, 'push');
            app.sinButton.ButtonPushedFcn = createCallbackFcn(app, @PressSin, true);
            app.sinButton.Text = 'sin';
            app.tanButton = uibutton(app.GridLayout, 'push');
            app.tanButton.ButtonPushedFcn = createCallbackFcn(app, @PressTan, true);
            app.tanButton.Text = 'tan';
            app.EXPButton = uibutton(app.GridLayout, 'push');
            app.EXPButton.ButtonPushedFcn = createCallbackFcn(app, @PressEXP, true);
            app.EXPButton.Text = 'EXP';
            app.cotButton = uibutton(app.GridLayout, 'push');
            app.cotButton.ButtonPushedFcn = createCallbackFcn(app, @PressCot, true);
            app.cotButton.Text = 'cot';
            app.piButton = uibutton(app.GridLayout, 'push');
            app.piButton.ButtonPushedFcn = createCallbackFcn(app, @Press, true);
            app.piButton.Text = 'pi';
            app.logButton = uibutton(app.GridLayout, 'push');
            app.logButton.ButtonPushedFcn = createCallbackFcn(app, @PressLog, true);
            app.logButton.Text = 'log';
            app.Button_10 = uibutton(app.GridLayout, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Press, true);
            app.Button_10.Text = '^';
            app.xButton = uibutton(app.GridLayout, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @PressFact, true);
            app.xButton.Text = 'x!';
            app.lnButton = uibutton(app.GridLayout, 'push');
            app.lnButton.ButtonPushedFcn = createCallbackFcn(app, @PressLn, true);
            app.lnButton.Text = 'ln';
            app.xButton_2 = uibutton(app.GridLayout, 'push');
            app.xButton_2.ButtonPushedFcn = createCallbackFcn(app, @PressInv, true);
            app.xButton_2.Text = '1/x';
            app.arccosButton = uibutton(app.GridLayout, 'push');
            app.arccosButton.ButtonPushedFcn = createCallbackFcn(app, @PressAcos, true);
            app.arccosButton.Text = 'arccos';
            app.arcsinButton = uibutton(app.GridLayout, 'push');
            app.arcsinButton.ButtonPushedFcn = createCallbackFcn(app, @PressAsin, true);
            app.arcsinButton.Text = 'arcsin';
            app.arctanButton = uibutton(app.GridLayout, 'push');
            app.arctanButton.ButtonPushedFcn = createCallbackFcn(app, @PressAtand, true);
            app.arctanButton.Text = 'arctan';
            app.arccotButton = uibutton(app.GridLayout, 'push');
            app.arccotButton.ButtonPushedFcn = createCallbackFcn(app, @PressAcotd, true);
            app.arccotButton.Text = 'arccot';
            app.Button_11 = uibutton(app.GridLayout, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Press, true);
            app.Button_11.Text = '(';
            app.Button_12 = uibutton(app.GridLayout, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Press, true);
            app.Button_12.Text = ')';
            app.TextArea_2Label = uilabel(app.GridLayout);
            app.TextArea_2Label.Text = 'Text Area';
            app.TextArea_2 = uitextarea(app.GridLayout);
            app.TextArea_2.Value = {'Some Notes About the Syntax:'; ''; '1) 5(2+3) is not working. You have to type 5*(2+3)'; ''; '2) Trigonometric functions are only working with degrees.'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ata_Kizilirmak_EE101_TermProject1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end